classdef viewGFP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SliderLabel  matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        UIAxes       matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        theImages % Description
        allTimes % Description
        yTimes
        y
        
    end
    
    properties (Access = private)
        imageHandle % Description
        lineHandle
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, theImages, allTimes, yTimes, y)
            app.theImages = theImages;
            app.allTimes = allTimes;
            app.yTimes = yTimes;
            app.y = y;
            
            app.imageHandle = imshow(app.theImages(:,:,1),'Parent',app.UIAxes);
            
            plot(app.UIAxes2,app.allTimes,(min(y)-10)*ones(1,length(app.allTimes)))
            hold on;
            plot(app.UIAxes2,app.yTimes,y,'r*')
            hold off;
            
            app.lineHandle = line(app.UIAxes2,[app.allTimes(1) app.allTimes(1)],[min(y) max(y)]);
            app.Slider.Limits = [1 length(allTimes)];
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            currFrame = round(changingValue);
            app.imageHandle.CData = app.theImages(:,:,currFrame);
            app.lineHandle.XData = [app.allTimes(currFrame) app.allTimes(currFrame)];
            %imshow(app.theImages(:,:,currFrame),'Parent',app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 61];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = viewGFP(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end